classdef layers_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        TabGroup           matlab.ui.container.TabGroup
        LoadTab            matlab.ui.container.Tab
        GridLayout2        matlab.ui.container.GridLayout
        StatusPanel        matlab.ui.container.Panel
        Label              matlab.ui.control.Label
        LoadImagePanel     matlab.ui.container.Panel
        LoadButton         matlab.ui.control.Button
        ButtonGroup        matlab.ui.container.ButtonGroup
        Button             matlab.ui.control.ToggleButton
        Button2            matlab.ui.control.ToggleButton
        Button3            matlab.ui.control.ToggleButton
        ImageTab           matlab.ui.container.Tab
        GridLayout2_2      matlab.ui.container.GridLayout
        ChooseFrameLabel   matlab.ui.control.Label
        ChooseFrameSlider  matlab.ui.control.Slider
    end

    
    properties (Access = private)
        ImageLoaderComponentHandler ImageLoaderComponent
        ImageComponentHandler ImageComponentHandler
        ImageDisplayHandler ImageDisplayHandler
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %TODO addpath
            addpath('..\classDefinitions\')
            imageBuilder=ImageBuilder;
            app.ImageDisplayHandler=ImageDisplayHandler;
            app.ImageComponentHandler=ImageComponentHandler(app.ImageDisplayHandler,imageBuilder);
            app.ImageLoaderComponentHandler=ImageLoaderComponent(app.ImageComponentHandler,imageBuilder);
            
            %hide elements-to move
            app.ImageTab.Parent=[];
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.ImageLoaderComponentHandler.loadImageData(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
            close all hidden force;
            close all force;
        end

        % Value changed function: ChooseFrameSlider
        function ChooseFrameSliderValueChanged(app, event)
            value = app.ChooseFrameSlider.Value;
            app.ImageDisplayHandler.show(app, round(value));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.LoadTab = uitab(app.TabGroup);
            app.LoadTab.Title = 'Load';
            app.GridLayout2 = uigridlayout(app.LoadTab);
            app.StatusPanel = uipanel(app.GridLayout2);
            app.StatusPanel.Title = 'Status';
            app.Label = uilabel(app.StatusPanel);
            app.LoadImagePanel = uipanel(app.GridLayout2);
            app.LoadImagePanel.Title = 'Load Image';
            app.LoadButton = uibutton(app.LoadImagePanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ButtonGroup = uibuttongroup(app.GridLayout2);
            app.ButtonGroup.Title = 'Button Group';
            app.Button = uitogglebutton(app.ButtonGroup);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uitogglebutton(app.ButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uitogglebutton(app.ButtonGroup);
            app.Button3.Text = 'Button3';
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.GridLayout2_2 = uigridlayout(app.ImageTab);
            app.ChooseFrameLabel = uilabel(app.GridLayout2_2);
            app.ChooseFrameLabel.Text = 'Choose Frame:';
            app.ChooseFrameSlider = uislider(app.GridLayout2_2);
            app.ChooseFrameSlider.ValueChangedFcn = createCallbackFcn(app, @ChooseFrameSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = layers_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end